classdef MatlabPressureApp_analyse2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FrameLabel                matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        InitializeButton          matlab.ui.control.Button
        SaveFootButton            matlab.ui.control.Button
        PressurePanel             matlab.ui.container.Panel
        AnalysefootButton         matlab.ui.control.Button
        FootAreaLabel             matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        FilenameEditFieldLabel    matlab.ui.control.Label
        FilenameEditField         matlab.ui.control.EditField
        StartFrameEditFieldLabel  matlab.ui.control.Label
        StartFrameEditField       matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel    matlab.ui.control.Label
        EndFrameEditField         matlab.ui.control.NumericEditField
        UpperXEditFieldLabel      matlab.ui.control.Label
        UpperXEditField           matlab.ui.control.NumericEditField
        UpperYEditFieldLabel      matlab.ui.control.Label
        UpperYEditField           matlab.ui.control.NumericEditField
        LowerXEditFieldLabel      matlab.ui.control.Label
        LowerXEditField           matlab.ui.control.NumericEditField
        LowerYEditFieldLabel      matlab.ui.control.Label
        LowerYEditField           matlab.ui.control.NumericEditField
        CurrentFrameSpinnerLabel  matlab.ui.control.Label
        CurrentFrameSpinner       matlab.ui.control.Spinner
        StepEditFieldLabel        matlab.ui.control.Label
        StepEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        frameNo % Description
        myData % Description 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrameSlider
        function changeFrame(app, event)
            app.frameNo = round(app.FrameSlider.Value);
            h = heatmap(app.PressurePanel,app.myData(:,:,app.frameNo));
            h.Colormap = jet;
            app.CurrentFrameSpinner.Value = round(app.FrameSlider.Value);
        end

        % Callback function: FilenameEditField, InitializeButton
        function initializeData(app, event)
            fileToLoad = app.FilenameEditField.Value
            load(fileToLoad,'dat');   
            app.myData = dat;
            h = heatmap(app.PressurePanel,app.myData(:,:,1));
            h.Colormap = jet;
            max = size(dat);
            app.FrameSlider.Limits = [1 max(3)];
            app.CurrentFrameSpinner.Value = 1;
        end

        % Button pushed function: AnalysefootButton
        function Analyze(app, event)
            %%Now data is imported.  Save it out as something sensible, then when dragging in it will appear as dat
            
            %%The following will be done PER FOOTFALL
            %Use footscan to get frames of interest (add some blank padding to be sure)
            %e.g. 595-704
            
            startFrame = app.StartFrameEditField.Value;
            endFrame = app.EndFrameEditField.Value;
            sizeFrame = endFrame-startFrame+1;
            
            temporalCrop = app.myData(:,:,startFrame:endFrame);
            %view temporalCrop to find coords for spatial temoporalCrop
            
            % change this value to half resulting number of frames, in this case ~ 1/2 of 110
            midRange = idivide(sizeFrame, int32(2), 'round')
           
            
            %look at heatmap and get relevant crop values:
            UpperX = app.UpperXEditField.Value;
            UpperY = app.UpperYEditField.Value;
            LowerX = app.LowerXEditField.Value;
            LowerY = app.LowerYEditField.Value;
            
            spatialCrop = temporalCrop(UpperY:LowerY,UpperX:LowerX,:);
            
            %you can sanity check with:
            heatmap(spatialCrop(:,:,midRange));
            
            %total area calculation per frame (cm2):
            for x=1:sizeFrame
                areaCalc(x) = nnz(spatialCrop(:,:,x))*0.387096;
            end
            
            %view area through footstep:
            %plot(areaCalc);
            
            %calculate total area over time:
            totalArea(:,:) = spatialCrop(:,:,1);
            for x=2:sizeFrame
                totalArea(:,:) = totalArea(:,:)+spatialCrop(:,:,x);
            end
            
            %sanity check it looks like an expected foot:
            %heatmap(totalArea)
            
            %area calculation  (not really foot area but near enough) in cm2
            footArea = nnz(totalArea)*0.387096;
            
            %% Force calculations - gets N/sensor
            
            forceValuesPerSensor = spatialCrop*0.387096;
            
            %force over time
            for x=1:sizeFrame
                forceOverTime(x) = sum(forceValuesPerSensor(:,:,x),'All');
            end
            
            %View vertical force trace - GRFv
            %plot(forceOverTime);
            
            %%Calculate pressure from calculated force and area
            for x=1:sizeFrame
                pressure(x) = forceOverTime(x)/areaCalc(x);
            end
            
            %check this
            %plot(pressure);
            
            
            plot(app.UIAxes,areaCalc);
            plot(app.UIAxes2,forceOverTime);
            plot(app.UIAxes3,pressure);
            
            
            message1 = sprintf('Foot outline area= %f cm^2', footArea);
            app.FootAreaLabel.Text = message1 + "cm2";
        end

        % Value changed function: CurrentFrameSpinner
        function manualFrame(app, event)
            value = app.CurrentFrameSpinner.Value;
            app.FrameSlider.Value = value;
            h = heatmap(app.PressurePanel,app.myData(:,:,value));
            h.Colormap = jet;
            
        end

        % Value changed function: StepEditField
        function changeStep(app, event)
            value = app.StepEditField.Value;
            app.CurrentFrameSpinner.Step = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 200];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @changeFrame, true);
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeData, true);
            app.InitializeButton.Text = 'Initialize';
            app.SaveFootButton = uibutton(app.UIFigure, 'push');
            app.SaveFootButton.Enable = 'off';
            app.SaveFootButton.Text = 'SaveFoot';
            app.PressurePanel = uipanel(app.UIFigure);
            app.PressurePanel.Title = 'Pressure';
            app.AnalysefootButton = uibutton(app.UIFigure, 'push');
            app.AnalysefootButton.ButtonPushedFcn = createCallbackFcn(app, @Analyze, true);
            app.AnalysefootButton.Text = 'Analyse foot';
            app.FootAreaLabel = uilabel(app.UIFigure);
            app.FootAreaLabel.Text = 'Foot Area:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Area over Time')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Total Force over time')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pressure over time')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @initializeData, true);
            app.FilenameEditField.Value = '<filename>';
            app.StartFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndFrameEditFieldLabel = uilabel(app.UIFigure);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperXEditFieldLabel = uilabel(app.UIFigure);
            app.UpperXEditFieldLabel.Text = 'UpperX';
            app.UpperXEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperYEditFieldLabel = uilabel(app.UIFigure);
            app.UpperYEditFieldLabel.Text = 'UpperY';
            app.UpperYEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerXEditFieldLabel = uilabel(app.UIFigure);
            app.LowerXEditFieldLabel.Text = 'LowerX';
            app.LowerXEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerYEditFieldLabel = uilabel(app.UIFigure);
            app.LowerYEditFieldLabel.Text = 'LowerY';
            app.LowerYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentFrameSpinnerLabel.Text = 'CurrentFrame';
            app.CurrentFrameSpinner = uispinner(app.UIFigure);
            app.CurrentFrameSpinner.Step = 10;
            app.CurrentFrameSpinner.RoundFractionalValues = 'on';
            app.CurrentFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @manualFrame, true);
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = '(Step)';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.Limits = [1 1000];
            app.StepEditField.RoundFractionalValues = 'on';
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @changeStep, true);
            app.StepEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabPressureApp_analyse2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end